classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MeasurementApp               matlab.ui.Figure
        StopButton                   matlab.ui.control.Button
        StartButton                  matlab.ui.control.Button
        StatusTable                  matlab.ui.control.Table
        Console                      matlab.ui.control.TextArea
        IncrementSizeEditFieldLabel  matlab.ui.control.Label
        IncrementSizeEditField       matlab.ui.control.NumericEditField
        PauseButton                  matlab.ui.control.StateButton
        IdleLampLabel                matlab.ui.control.Label
        IdleLamp                     matlab.ui.control.Lamp
        InMotionLampLabel            matlab.ui.control.Label
        InMotionLamp                 matlab.ui.control.Lamp
        MeasuringLampLabel           matlab.ui.control.Label
        MeasuringLamp                matlab.ui.control.Lamp
        HornImage                    matlab.ui.control.UIAxes
        ProgressBar                  matlab.ui.container.Panel
    end

    
    properties (Access = public)
    
        consoleObj
        MI4190Obj
        loadBarObj
        wantToStop = false;
        imageObj;
        imageHandleObj;
        axObj;
        phObj;
        thObj;
    
    end

    methods (Access = public)
        
        function writeConsoleLine(app,lineIn)
            app.Console.Value = [lineIn;app.Console.Value];
        end
        
        function rotateHornImage(app,rotation)
            app.imageHandleObj.CData = imrotate(app.imageObj,rotation);
        end
        
        function loadImage(app)
            im = imread('SSHorn.png');
            imh = imshow(im,'Parent',app.HornImage);
            app.imageHandleObj = imh;
            app.imageObj = im;
        end
        
        function createProgressBar(app)
            ax = axes(app.ProgressBar,'Position',[.1 .4 .8 .1],'box','on','xtick',[],'ytick',[],...
    'color',[0.65 0.65 0.65],'xlim',[0,1],'ylim',[0,1]);
            title(ax,'Initializing. . .')
            % Create empty patch that will be updated
            ph = patch(ax,[0 0 0 0],[0 0 1 1],[0 1 0]);
            % Create the percent-complete text that will be updated
            th = text(ax,1,1,'0%','VerticalAlignment','bottom','HorizontalAlignment','right');
            
            app.axObj = ax;
            app.phObj = ph;
            app.thObj = th;
             
        end
        
        function updateProgressBar(app,progress,lineIn)
                        
            app.phObj.XData = [0 progress progress 0];
            app.thObj.String = sprintf('%.0f%%',round(progress*100));
           
            title(app.axObj,lineIn);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            loadImage(app);            
            createProgressBar(app);
                        
            app.consoleObj.line1 = 'Console Started';
            app.Console.Value = {app.consoleObj.line1};
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            writeConsoleLine(app,'Start Button pressed');
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            
            MeasurementAppCloseRequest(app);
        end

        % Close request function: MeasurementApp
        function MeasurementAppCloseRequest(app, event)
            app.writeConsoleLine(sprintf('[%s] Stopping Measurements. . .',datestr(now,'HH:MM:SS.FFF')));
            fprintf('[%s] Stopping Measurements. . .\n',datestr(now,'HH:MM:SS.FFF'))
            
            app.wantToStop = true;
            if (~isempty(app.loadBarObj))
                delete(app.loadBarObj);
                clear app.loadBarObj;
            end
            
            if (~isempty(app.MI4190Obj))
                fclose(app.MI4190Obj);
                delete(app.MI4190Obj);
                clear app.MI4190Obj;
            else
                delete(app);
            end
      
        end

        % Value changed function: PauseButton
        function PauseButtonValueChanged(app, event)
            value = app.PauseButton.Value;
            app.writeConsoleLine('pressed Pause button');
           
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MeasurementApp = uifigure('Visible', 'off');
            app.MeasurementApp.Name = 'Measurement App';
            app.MeasurementApp.CloseRequestFcn = createCallbackFcn(app, @MeasurementAppCloseRequest, true);
            app.StopButton = uibutton(app.MeasurementApp, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.StartButton = uibutton(app.MeasurementApp, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StatusTable = uitable(app.MeasurementApp);
            app.StatusTable.ColumnName = {'Value'};
            app.StatusTable.RowName = {'Current Position: '; 'Next Position: '; 'Current Velocity: '; 'Angles Remaining: '};
            app.Console = uitextarea(app.MeasurementApp);
            app.Console.Editable = 'off';
            app.IncrementSizeEditFieldLabel = uilabel(app.MeasurementApp);
            app.IncrementSizeEditFieldLabel.Text = 'Increment Size';
            app.IncrementSizeEditField = uieditfield(app.MeasurementApp, 'numeric');
            app.IncrementSizeEditField.Limits = [0 180];
            app.PauseButton = uibutton(app.MeasurementApp, 'state');
            app.PauseButton.ValueChangedFcn = createCallbackFcn(app, @PauseButtonValueChanged, true);
            app.PauseButton.Text = 'Pause';
            app.IdleLampLabel = uilabel(app.MeasurementApp);
            app.IdleLampLabel.Text = 'Idle';
            app.IdleLamp = uilamp(app.MeasurementApp);
            app.InMotionLampLabel = uilabel(app.MeasurementApp);
            app.InMotionLampLabel.Text = 'In Motion';
            app.InMotionLamp = uilamp(app.MeasurementApp);
            app.MeasuringLampLabel = uilabel(app.MeasurementApp);
            app.MeasuringLampLabel.Text = 'Measuring';
            app.MeasuringLamp = uilamp(app.MeasurementApp);
            app.HornImage = uiaxes(app.MeasurementApp);
            title(app.HornImage, '')
            xlabel(app.HornImage, '')
            ylabel(app.HornImage, '')
            app.ProgressBar = uipanel(app.MeasurementApp);
            % Show the figure after all components are created
            app.MeasurementApp.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MeasurementApp)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MeasurementApp)
        end
    end
end